classdef video_i < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Image        matlab.ui.control.Image
        PHOTOButton  matlab.ui.control.Button
        Lamp         matlab.ui.control.Lamp
        salida       matlab.ui.control.EditField
        STARTButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
           global imagestats_f
           global cam
           global fig2
           cam_v=webcam;
           frame=snapshot(cam_v);
           im=image(app.UIAxes, zeros(size(frame),"uint8"));
           axis(app.UIAxes,"image");

           preview(cam_v,im);
           pause(1);

           while true
                cam = webcam; % Inicializar la webcam
                pause(0.5); % Pausar para que la webcam se estabilice

                fig1 = snapshot(cam); % Tomar la primera imagen
                fig1_gray = rgb2gray(fig1);
                %imtool(fig1);
                %imtool(fig1_gray);

                pause(0.5); % Pausar para permitir cambios en la escena

                fig2 = snapshot(cam); % Tomar la segunda imagen
                fig2_gray = rgb2gray(fig2);
                %imtool(fig2);
                %imtool(fig2_gray);

                dif = abs(fig1_gray - fig2_gray);
                %imtool(dif);

                % Mostrar el punto con mayor diferencia
                maxdif = max(max(dif));
                [iReg, iCol] = find(dif == maxdif);
                [m, n] = size(dif);
   
                % Filtro 1: Umbral (Treshhold) de la imagen
                Thresh = dif > 30; %Variable lógica
                %figure

                % Filtro 2: Rellenar huecos de la imagen
                figrellena = bwareaopen(Thresh, 30);
                %figure

                % Filtro 3: de objetos de longitud determinada
                figstats = regionprops(figrellena, "MajorAxisLength");

                imglengths = [figstats.MajorAxisLength];
                idx = imglengths > 150;
                imagestats_f = figstats(idx);
                %disp(imagestats_f);
                % Alarma: Emitir una nota de alarma
                if isempty(imagestats_f)
                    app.salida.Value="TODO BIEN";
                    app.Lamp.Color="Green";
                else
                    beep on
                    app.salida.Value="¡HAY UN INTRUSO!";
                    beep
                    app.Lamp.Color="Red";
                    %img=snapshot(cam);
                    %imwrite(img, "intruso.jpg");
                    fecha_hora = datestr(now, 'yyyymmdd_HHMM');
                    imwrite(fig2, strcat('intruso_', fecha_hora, '.jpg'));
                    %break
                end
                clear cam % Liberar la memoria de la webcam
           end
        end

        % Value changed function: salida
        function salidaValueChanged(app, event)
            value = app.salida.Value;
 
        end

        % Callback function
        function StopButtonPushed(app, event)
         
        end

        % Button pushed function: PHOTOButton
        function PHOTOButtonPushed(app, event)
            global fig2
            imtool(fig2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.salida = uieditfield(app.UIFigure, 'text');
            app.salida.ValueChangedFcn = createCallbackFcn(app, @salidaValueChanged, true);
            app.salida.Editable = 'off';
            app.Lamp = uilamp(app.UIFigure);
            app.PHOTOButton = uibutton(app.UIFigure, 'push');
            app.PHOTOButton.ButtonPushedFcn = createCallbackFcn(app, @PHOTOButtonPushed, true);
            app.PHOTOButton.Text = 'PHOTO';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '202779653a9a408aac461fcd1465f3d5.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = video_i

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end